classdef DataViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        ContextdependentwhikerdetectiontaskDataViewerLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        XaxisLimit                   matlab.ui.control.TextArea
        TextArea2Label               matlab.ui.control.Label
        FixXaxisCheckBox             matlab.ui.control.CheckBox
        YaxisLimit                   matlab.ui.control.EditField
        limitminmaxLabel             matlab.ui.control.Label
        completionStateBox           matlab.ui.control.ListBox
        early_lickcomplete_Label     matlab.ui.control.Label
        FixYaxisCheckBox             matlab.ui.control.CheckBox
        SaveNotesButton              matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        TextAreaLabel                matlab.ui.control.Label
        PlotSEMCheckBox              matlab.ui.control.CheckBox
        BaselineSubtractionCheckBox  matlab.ui.control.CheckBox
        CellTypeListBox              matlab.ui.control.ListBox
        CellTypeListBoxLabel         matlab.ui.control.Label
        QuiesenceListBox             matlab.ui.control.ListBox
        QuiesenceListBoxLabel        matlab.ui.control.Label
        LickStateListBox             matlab.ui.control.ListBox
        LickStateListBoxLabel        matlab.ui.control.Label
        TrialTypeListBox             matlab.ui.control.ListBox
        TrialTypeListBoxLabel        matlab.ui.control.Label
        A1CheckBoxpre                matlab.ui.control.CheckBox
        S1CheckBoxpre                matlab.ui.control.CheckBox
        S2CheckBoxpre                matlab.ui.control.CheckBox
        wM2CheckBoxpre               matlab.ui.control.CheckBox
        ALMCheckBoxpre               matlab.ui.control.CheckBox
        SelectedTextArea             matlab.ui.control.TextArea
        SelectedTextAreaLabel        matlab.ui.control.Label
        SessionsListBox              matlab.ui.control.ListBox
        SessionsListBoxLabel         matlab.ui.control.Label
        movement_ax                  matlab.ui.control.UIAxes
        STA                          matlab.ui.control.UIAxes
        connectivitymat              matlab.ui.control.UIAxes
        distribution                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        variables % Description
    end


    methods (Access = private)


        function plotpsth(app)

            meansig=nanmean(app.variables.signal2plot,2)/.01;

            if app.variables.PlotSEM
            semsig=nanstd(app.variables.signal2plot,[],2)./sqrt(size(app.variables.signal2plot,2));
            curve1 = meansig+semsig;
            curve2 = meansig-semsig;
            x2 = [app.variables.time2plot',fliplr(app.variables.time2plot')];
            inBetween = [curve1', fliplr(curve2')];
            fill(app.STA,x2, inBetween ,app.variables.colorcode,'FaceAlpha',0.3,'LineStyle','none');
            end

            hold (app.STA,'on')
            plot(app.STA,app.variables.time2plot,meansig,'color',app.variables.colorcode,'linewidth',1.5)
            YLIMS=ylim(app.STA)
            line(app.STA,[1 1]+app.variables.shiftx,YLIMS,'LineWidth',1.5,'LineStyle','-','Color',[236 176 31]/255)
            line(app.STA,[0 0]+app.variables.shiftx,YLIMS,'LineWidth',1.5,'LineStyle','-','Color',[236 176 31]/255)
        end


        function Signal_allArea_allCondition = UpdataPsthPlot(app)

            cla(app.STA)
            Signal_allArea_allCondition=[];

            for AreaCounter=1:numel(app.variables.Areas_SelectedSession)

                CurrentArea=cell2mat(app.variables.Areas_SelectedSession(AreaCounter));

                Curr_Index=app.variables.Indecies_SelectedSession(AreaCounter) % index of current session in PSTHMat, we need this to compute all we nneed
                Signal_CurrArea_allCondition=[]
                for CondCounter=1%:size(CurrentAreaConditions,1)  % trial condition to plot 

                    % 5 if we select more than one session to plot
                    Concatsig=[]
                    % for i=1:length(PSTH.(area).Group1.spikerate)


                      Trial=app.variables.PSTHMat(Curr_Index).trial_type;
                      Lick= app.variables.PSTHMat(Curr_Index).lick_flag;
                    

                    % conditions
                    IndTrialType=Trial==app.variables.TrialType;
                    IndLickstate=Lick==app.variables.LickState ;


                    % id of completion
                    switch app.variables.completion_state

                        case 'completed_trials'
                            completion_state=~app.variables.PSTHMat(Curr_Index).early_lick;
                        case 'early_licks'
                            early_licks_all=app.variables.PSTHMat(Curr_Index).early_lick;
                            lick_time=0<(app.variables.PSTHMat(Curr_Index).lick_time-app.variables.PSTHMat(Curr_Index).start_time);
                            completion_state=lick_time & early_licks_all;

                    end


                      % ids of quesense 
                    switch app.variables.QuietState

                        case 'Quiet_(whisker_speed)'
                            Qind=app.variables.PSTHMat(Curr_Index).quiet_trial_whisker_speed;
                        case 'Quiet_(jaw_movement)'
                           Qind=app.variables.PSTHMat(Curr_Index).quiet_trial_jaw_movement;

                        case 'Quiet_(jaw & whisker)'
                                 Qind=app.variables.PSTHMat(Curr_Index).quiet_trial_jaw_movement & app.variables.PSTHMat(Curr_Index).quiet_trial_whisker_speed;
                        case 'Non_quiet'
                            Qind=~(app.variables.PSTHMat(Curr_Index).quiet_trial_jaw_movement & app.variables.PSTHMat(Curr_Index).quiet_trial_whisker_speed);
                        case 'All_trials'
                            Qind=ones(length(IndLickstate),1);
                    end


                    Trial_ind2plot=[Qind&completion_state&IndLickstate&IndTrialType];


                    switch app.variables.CellType

                        case 'RS'
                            CurrCellInd=app.variables.PSTHMat(Curr_Index).unit_rsUnits;
                        case 'FS'
                            CurrCellInd=app.variables.PSTHMat(Curr_Index).unit_fsUnits;
                        case 'All'

                            CurrCellInd=(app.variables.PSTHMat(Curr_Index).unit_fsUnits | app.variables.PSTHMat(Curr_Index).unit_rsUnits) ;
                    end


                    % current psth of selected session
                    Currsig=app.variables.PSTHMat(Curr_Index).spike_counts;
                    % take the average over specific trials condition
                    Currsig_CurrTrialInd=squeeze(nanmean(Currsig(:,Trial_ind2plot,:),2));
                    % for specified cell types
                    Currsig_CurrTrialInd_CurrCellInd=Currsig_CurrTrialInd(:,CurrCellInd);
                    WindowCenters=app.variables.PSTHMat(Curr_Index).trial_timestamps ;   % change later
                    if app.variables.BaselineSubtraction
                        t1=-1
                        t2=0
                        [a,b]=min(abs(WindowCenters-t1));baselineFirstBin=(b);
                        [a,b]=min(abs(WindowCenters-t2));baselineLastBin=(b);
                        MeanMat=repmat(mean(Currsig_CurrTrialInd_CurrCellInd(baselineFirstBin:baselineLastBin,:),1),size(Currsig_CurrTrialInd_CurrCellInd,1),1);
                        Currsig_CurrTrialInd_CurrCellInd=Currsig_CurrTrialInd_CurrCellInd-MeanMat;
                    end


                    Concatsig=[Concatsig,Currsig_CurrTrialInd_CurrCellInd];


                    [a,b]=min(abs(WindowCenters+1)),Win(1)=(b)
                    [a,b]=min(abs(WindowCenters-2)),Win(2)=(b)

                    app.variables.signal2plot=Concatsig(Win(1):Win(2),:);
                    app.variables.time2plot=WindowCenters(Win(1):Win(2));
                    app.variables.shiftx=0
                    app.variables.shifty=0
                    indcolor=find(strcmp(CurrentArea,app.variables.Map(:,1)));

                    app.variables.colorcode=tools.hex2rgb(cell2mat(app.variables.Map(indcolor,2)))
                    plotpsth(app)


                    Signal_CurrArea_allCondition(:,:,CondCounter)=Concatsig;


                end

                Signal_allArea_allCondition.(CurrentArea)=Signal_CurrArea_allCondition;


            end
        end


        function PlotBehaviourPerformance(app)
            cla(app.connectivitymat)
            Curr_Index=app.variables.Indecies_SelectedSession(1)  % index of current probe (in one session there is more than 1 probe but behavior data for all probes are identical so i choose id=1
            NumTrials=sum(~app.variables.PSTHMat(Curr_Index).early_lick)  % extract vcompleted trials 


            % conditions 
            TrialType=app.variables.PSTHMat(Curr_Index).trial_type;
            LickState=app.variables.PSTHMat(Curr_Index).lick_flag ;
            completed_trials=~app.variables.PSTHMat(Curr_Index).early_lick;
            TrialType=TrialType(completed_trials);
            LickState=LickState(completed_trials);


            WindowLength=30
            TrialsCounter=WindowLength:NumTrials;


            Gotone_whisk=zeros(1,length(TrialsCounter));
            Gotone_Nowhisk=zeros(1,length(TrialsCounter));
            NoGotone_whisk=zeros(1,length(TrialsCounter));
            NoGotone_Nowhisk=zeros(1,length(TrialsCounter));
            Noaudio_whisk=zeros(1,length(TrialsCounter));


            Counter=0;
            for ThisTrial=TrialsCounter
                Counter=Counter+1;
                Indices=ThisTrial-WindowLength+1:ThisTrial;

                Gotone_whisk(Counter)=sum(TrialType(Indices)==1 & LickState(Indices)==1 )/sum(TrialType(Indices)==1)*100;
                Gotone_Nowhisk(Counter)=sum(TrialType(Indices)==2 & LickState(Indices)==1 )/sum(TrialType(Indices)==2)*100;
                NoGotone_whisk(Counter)=sum(TrialType(Indices)==3 & LickState(Indices)==1 )/sum(TrialType(Indices)==3)*100;
                NoGotone_Nowhisk(Counter)=sum(TrialType(Indices)==4 & LickState(Indices)==1)/sum(TrialType(Indices)==4)*100;
                Noaudio_whisk(Counter)=sum(TrialType(Indices)==5 & LickState(Indices)==1 )/sum(TrialType(Indices)==5)*100;
            end


            x1=1:NumTrials;

            hold (app.connectivitymat,'on')
            %     stairs(hAx(1),x1,FalseAlarm,'LineWidth',1.3,'Color','r')
            %     stairs(hAx(1),x1,NoGotone_whisk,'LineWidth',1.3,'Color','g')
            %     stairs(hAx(1),x1,Gotone_Nowhisk,'LineWidth',1.3,'Color','b')

            %    stairs(hAx(1),x1,FalseAlarm,'r','LineWidth',2)
            stairs(app.connectivitymat,TrialsCounter,Gotone_whisk,'color',tools.hex2rgb('#0008FF',1),'LineWidth',1.5)
            stairs(app.connectivitymat,TrialsCounter,Gotone_Nowhisk,'color',tools.hex2rgb('#00C3FF',1),'LineWidth',1.5)
            stairs(app.connectivitymat,TrialsCounter,NoGotone_whisk,'color',tools.hex2rgb('#FF0000',1),'LineWidth',1.5)
            stairs(app.connectivitymat,TrialsCounter,NoGotone_Nowhisk,'color',tools.hex2rgb('#FF7F00',1),'LineWidth',1.5)
            stairs(app.connectivitymat,TrialsCounter,Noaudio_whisk,'color',tools.hex2rgb('#000000',1),'LineWidth',1.5)


        end
        
        function PlotProbeLocation(app)


            cla(app.distribution)
            hold(app.distribution,'on');

            [~, brain_outline] = plotBrainGrid([],app.distribution);
            set(app.distribution,'ZDir','reverse');

             axis(app.distribution,'vis3d','equal','off','manual')
            view(app.distribution,[0,90]);
            set(app.distribution,'CLim',[0 600]);%clim(app.distribution,[0 600]);
            [ap_max,dv_max,ml_max] = size(app.variables.tv);
            xlim(app.distribution,[0,1000])
            ylim(app.distribution,[0,600])
            zlim(app.distribution,[0,500])
            % plot bregma
            bregma = allenCCFbregma()
            plot3(app.distribution,bregma(:,1), ...
                bregma(:,3), ...
                bregma(:,2), ...
                '+','color','k','MarkerSize',15,'linewidth',3);


        % plot all probes location in small     
        probes_Ind=1:size([app.variables.PSTHMat.session_id]',1)
        probes_Area=[app.variables.PSTHMat.probe_location];
        for iprobes_Ind=1:numel(probes_Ind)

            CurrentArea=probes_Area{iprobes_Ind};
            CurrentInd=probes_Ind(iprobes_Ind)


         points=mean([app.variables.PSTHMat(CurrentInd).elec_ccf_ap,app.variables.PSTHMat(CurrentInd).elec_ccf_dv,app.variables.PSTHMat(CurrentInd).elec_ccf_ml]);
         if isempty(points)

        continue
        end
         indcolor=find(strcmp(CurrentArea,app.variables.Map(:,1)));
         plot3(app.distribution,points(1,1),points(1,3),points(1,2), ...
        'o','color',tools.hex2rgb(cell2mat(app.variables.Map(indcolor,2))),'MarkerSize',3);

        end


        % plot current points 
        probes_Ind=app.variables.Indecies_SelectedSession
        probes_Area=app.variables.Areas_SelectedSession
        for iprobes_Ind=1:numel(probes_Ind)

            CurrentArea=cell2mat(app.variables.Areas_SelectedSession(iprobes_Ind));
            CurrentInd=probes_Ind(iprobes_Ind)


         points=mean([app.variables.PSTHMat(CurrentInd).elec_ccf_ap,app.variables.PSTHMat(CurrentInd).elec_ccf_dv,app.variables.PSTHMat(CurrentInd).elec_ccf_ml]);

         indcolor=find(strcmp(CurrentArea,app.variables.Map(:,1)));
         plot3(app.distribution,points(1,1),points(1,3),points(1,2), ...
        '.','color',tools.hex2rgb(cell2mat(app.variables.Map(indcolor,2))),'MarkerSize',20);

        end


        end


        function PlotMovementsSignal(app)
            
            cla(app.movement_ax)

        
                SignalMovements_allArea_allCondition=[];

                Curr_Index=app.variables.Indecies_SelectedSession(1); % index of current session in PSTHMat, we need this to compute all we nneed
                CurrentArea=cell2mat(app.variables.Areas_SelectedSession(1));
                Cond=[CurrentArea 'Conditions']
                CurrentAreaConditions=[app.variables.(Cond).TrialType',app.variables.(Cond).LickState'];
                for CondCounter=1%:size(CurrentAreaConditions,1)
                    CurrentCondition=CurrentAreaConditions(CondCounter,:)

                    % 5 if we select more than one session to plot
                    Concatsig=[]
                    % for i=1:length(PSTH.(area).Group1.spikerate)


                    Trial=app.variables.PSTHMat(Curr_Index).trial_type;
                    Lick= app.variables.PSTHMat(Curr_Index).lick_flag;
                    

                    % conditions
                    IndTrialType=Trial==app.variables.TrialType;
                    IndLickstate=Lick==app.variables.LickState ;


                    % id of completion
                    switch app.variables.completion_state

                        case 'completed_trials'
                            completion_state=~app.variables.PSTHMat(Curr_Index).early_lick;
                        case 'early_licks'
                            early_licks_all=app.variables.PSTHMat(Curr_Index).early_lick;
                            lick_time=0<(app.variables.PSTHMat(Curr_Index).lick_time-app.variables.PSTHMat(Curr_Index).start_time);
                            completion_state=lick_time & early_licks_all;

                    end


                      % ids of quesense 
                    switch app.variables.QuietState

                        case 'Quiet_(whisker_speed)'
                            Qind=app.variables.PSTHMat(Curr_Index).quiet_trial_whisker_speed;
                        case 'Quiet_(jaw_movement)'
                           Qind=app.variables.PSTHMat(Curr_Index).quiet_trial_jaw_movement;

                        case 'Quiet_(jaw & whisker)'
                                 Qind=app.variables.PSTHMat(Curr_Index).quiet_trial_jaw_movement & app.variables.PSTHMat(Curr_Index).quiet_trial_whisker_speed;
                        case 'Non_quiet'
                            Qind=~(app.variables.PSTHMat(Curr_Index).quiet_trial_jaw_movement & app.variables.PSTHMat(Curr_Index).quiet_trial_whisker_speed);
                        case 'All_trials'
                            Qind=ones(length(IndLickstate),1);
                    end


                         Trial_ind2plot=[Qind&completion_state&IndLickstate&IndTrialType];


                    % current psth of selected session
                    CurrWhiskSpeed=app.variables.PSTHMat(Curr_Index).whisker_speed;
                    CurrJawMovement=app.variables.PSTHMat(Curr_Index).jaw_movement;
                    % take the average over specific trials condition
                    CurrWhiskSpeed_CurrTrialInd=squeeze((CurrWhiskSpeed(:,Trial_ind2plot,:)));
                    CurrJawMovement_CurrTrialInd=squeeze((CurrJawMovement(:,Trial_ind2plot,:)));

                    WindowCenters=app.variables.PSTHMat(Curr_Index).trial_timestamps;   % change later

                    
                    if app.variables.BaselineSubtraction
                        t1=-1
                        t2=0
                        [a,b]=min(abs(WindowCenters-t1));baselineFirstBin=(b);
                        [a,b]=min(abs(WindowCenters-t2));baselineLastBin=(b);


                        % for whisker speed 
                        MeanMatWhiskSpeed=repmat(nanmean(CurrWhiskSpeed_CurrTrialInd(baselineFirstBin:baselineLastBin,:),1),size(CurrWhiskSpeed_CurrTrialInd,1),1);
                        STDMatWhiskSpeed=repmat(nanstd(CurrWhiskSpeed_CurrTrialInd(baselineFirstBin:baselineLastBin,:),0,1),size(CurrWhiskSpeed_CurrTrialInd,1),1);
                        CurrWhiskSpeed_CurrTrialInd=(CurrWhiskSpeed_CurrTrialInd-MeanMatWhiskSpeed)./STDMatWhiskSpeed;
                        % for jaw movements 
                        MeanMatJawMovement=repmat(nanmean(CurrJawMovement_CurrTrialInd(baselineFirstBin:baselineLastBin,:),1),size(CurrJawMovement_CurrTrialInd,1),1);
                        STDMatJawMovement=repmat(nanstd(CurrJawMovement_CurrTrialInd(baselineFirstBin:baselineLastBin,:),0,1),size(CurrJawMovement_CurrTrialInd,1),1);
                        CurrJawMovement_CurrTrialInd=(CurrJawMovement_CurrTrialInd-MeanMatJawMovement)./STDMatJawMovement;

                    end


                    [a,b]=min(abs(WindowCenters+1)),Win(1)=(b)
                    [a,b]=min(abs(WindowCenters-2)),Win(2)=(b)


                    app.variables.time2plot=WindowCenters(Win(1):Win(2));
                    app.variables.shiftx=0
                    app.variables.shifty=-10

                    app.variables.colorcode=tools.hex2rgb('#8b008b')
                    app.variables.signal2plot=CurrJawMovement_CurrTrialInd(Win(1):Win(2),:);
                    plotpsth_mov(app)

                    app.variables.colorcode=tools.hex2rgb('#da9100')
                    app.variables.signal2plot=CurrWhiskSpeed_CurrTrialInd(Win(1):Win(2),:);
                    plotpsth_mov(app)


                end  


        end
        
        function results = plotpsth_mov(app)


            meansig=nanmean(app.variables.signal2plot,2);

            if app.variables.PlotSEM
            semsig=nanstd(app.variables.signal2plot,[],2)./sqrt(size(app.variables.signal2plot,2));
            curve1 = meansig+semsig;
            curve2 = meansig-semsig;
            x2 = [app.variables.time2plot',fliplr(app.variables.time2plot')];
            inBetween = [curve1', fliplr(curve2')];
            fill(app.movement_ax,x2, inBetween ,app.variables.colorcode,'FaceAlpha',0.3,'LineStyle','none');
            end

            hold (app.movement_ax,'on')
            plot(app.movement_ax,app.variables.time2plot,meansig,'color',app.variables.colorcode,'linewidth',1.5)
            YLIMS=ylim(app.movement_ax)
            line(app.movement_ax,[1 1],YLIMS,'LineWidth',1.5,'LineStyle','-','Color',[236 176 31]/255)
            line(app.movement_ax,[0 0],YLIMS,'LineWidth',1.5,'LineStyle','-','Color',[236 176 31]/255)


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          addpath(genpath('D:\analysis\analysis\Histology\AP_histology-master\AP_histology-master_recent'))
          linkaxes([app.STA, app.movement_ax], 'x')  % linking mov and psth axix 
              app.STA.XLim = [-1,2];


        s = uistyle(FontColor=tools.hex2rgb('#0008FF',1));
            addStyle(app.TrialTypeListBox,s,"item",1)
        s = uistyle(FontColor=tools.hex2rgb('#00C3FF',1));
            addStyle(app.TrialTypeListBox,s,"item",2)
        s = uistyle(FontColor=tools.hex2rgb('#FF0000',1));
            addStyle(app.TrialTypeListBox,s,"item",3)
        s = uistyle(FontColor=tools.hex2rgb('#FF7F00',1));
            addStyle(app.TrialTypeListBox,s,"item",4)
        s = uistyle(FontColor=tools.hex2rgb('#000000',1));
            addStyle(app.TrialTypeListBox,s,"item",5)


            allen_atlas_path = 'D:\analysis\analysis\Histology\AP_histology-master\AP_histology-master\data';
            app.variables.tv = readNPY([allen_atlas_path filesep 'template_volume_10um.npy']);
            app.variables.av = readNPY([allen_atlas_path filesep 'annotation_volume_10um_by_index.npy']);
            app.variables.st = AP_loadStructureTree([allen_atlas_path filesep 'structure_tree_safe_2017.csv']);

            [~, brain_outline] = plotBrainGrid([],app.distribution);
            set(app.distribution,'ZDir','reverse');
            hold(app.distribution,'on');

            axis(app.distribution,'vis3d','equal','off','manual')
            view(app.distribution,[90,90]);
            app.distribution.CLim=[0 300];%CLim(app.distribution,[0 300]);
            [ap_max,dv_max,ml_max] = size(app.variables.tv);
            xlim(app.distribution,[-10,ap_max+10])
            ylim(app.distribution,[-10,ml_max+10])
            zlim(app.distribution,[-10,dv_max+10])
            % plot bregma
            bregma = allenCCFbregma()
            plot3(app.distribution,bregma(:,1), ...
                bregma(:,3), ...
                bregma(:,2), ...
                '+','color','k','MarkerSize',15,'linewidth',3);


            % my code
            app.variables.PSTHMat=evalin("base",'psth_mat')
            app.variables.AreaNames=unique([app.variables.PSTHMat.probe_location]);
            app.variables.SesssionNames=unique([app.variables.PSTHMat.session_id])';
            app.SessionsListBox.Items=app.variables.SesssionNames
            %


              if ~isfield('Notes',app.variables.PSTHMat)

              app.variables.PSTHMat(1).Notes{:}='OK'

             end


            if ~isfield(app.variables,'SelectedSession')
                value = app.SessionsListBox.Value;
                app.variables.SelectedSession=value;
                %initialize checkboxes
                app.A1CheckBoxpre.Enable='off'

                app.S1CheckBoxpre.Enable='off'

                app.S2CheckBoxpre.Enable='off'

                app.wM2CheckBoxpre.Enable='off'

                app.ALMCheckBoxpre.Enable='off'
            end


            %


            % my code
            sessionname=app.variables.SelectedSession  % selected session
            app.variables.Indecies_SelectedSession=find(strcmp(sessionname,[app.variables.PSTHMat.session_id])) %index of probes that have same session
            app.variables.Areas_SelectedSession=app.variables.PSTHMat(app.variables.Indecies_SelectedSession).probe_location                                             % area in that session that recorded simultaneously
            % end my code


            %which area are in current session
            for Iarea=1:length(app.variables.Areas_SelectedSession);
                CurrArea=cell2mat(app.variables.Areas_SelectedSession(Iarea));

                switch CurrArea

                    case 'A1'
                        app.A1CheckBoxpre.Enable='on'

                    case 'wS1'
                        app.S1CheckBoxpre.Enable='on'

                    case 'wS2'
                        app.S2CheckBoxpre.Enable='on'

                    case 'wM2'
                        app.wM2CheckBoxpre.Enable='on'

                    case 'ALM'
                        app.ALMCheckBoxpre.Enable='on'


                end

            end
            % end of my code


            %%%%%%%%%%%%%%%%%%%% start of my code

            app.variables.colormap={'#0008FF';'#228B22';'#00C3FF';'#FF0000';'#FF7F00';'#000000';'#00C3FF';'#FF7F00';'#FF7F00';'#808080';'#6B3686';'#74B99A';'#A020F0';'#FFC0CB';'#FFD700'};app.variables.colortype={'wS1';'wS2';'CR2';'ALM';'CR4';'wM2';'FA2';'FA3';'FA4';'FA5';'Lick';'NoLick';'A1';'unassigned_area';'tjM1'};
            app.variables.Map=horzcat(app.variables.colortype,app.variables.colormap);

            % for behavior


            % conditions per regions
            % for A1
            app.variables.A1Conditions.TrialType=[1,2]
            app.variables.A1Conditions.LickState=[1,0]

            % for wS1
            app.variables.wS1Conditions.TrialType=[1,2]
            app.variables.wS1Conditions.LickState=[1,0]


            % for wS2
            app.variables.wS2Conditions.TrialType=[1,2]
            app.variables.wS2Conditions.LickState=[1,0]

            % for wM2
            app.variables.wM2Conditions.TrialType=[1,2]
            app.variables.wM2Conditions.LickState=[1,0]

            % for ALM
            app.variables.ALMConditions.TrialType=[1,2]
            app.variables.ALMConditions.LickState=[1,0]

            % for tjM1
            app.variables.tjM1Conditions.TrialType=[1,2]
            app.variables.tjM1Conditions.LickState=[1,0]

            % for unassigned_area
            app.variables.unassigned_areaConditions.TrialType=[1,2]
            app.variables.unassigned_areaConditions.LickState=[1,0]


           % plot which trial type  and Lick state
            app.variables.LickState=1
            app.variables.TrialType=1

            app.variables.QuietState='All_trials';
            app.variables.CellType='RS'
            app.variables.BaselineSubtraction=1
            app.variables.PlotSEM=0
            app.variables.completion_state='completed_trials'  

            % plot neurons response 
            Signal_allArea_allCondition = UpdataPsthPlot(app)
            % plot behavior performance 
            PlotBehaviourPerformance(app)
            % plot movement  signal 
            PlotMovementsSignal(app)


        end

        % Callback function
        function SelectpairButtonPushed(app, event)
            %         app.connectivitymat.HandleVisibility = 'callback';
            flg =1
            while flg
                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility ="callback" ; % Temp change (or, 'on')
                set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
                app.UIFigure.HandleVisibility
                [x,y,b] = ginput(1);
                % run ginput
                if or(x<0,y<0)
                    continue
                end
                [STA_ij, winCcenters]  = PSTH_Simple( app.variables.SpikeTimes{round(x)},  app.variables.SpikeTimes{round(y)}, -.2, .2, 0.001);
                plot(app.STA,winCcenters*1000, mean(STA_ij,2), 'LineWidth', 1.5);xline(app.STA,0)
                title(app.STA,[num2str(round(y)),' to ',num2str(round(x))])
                app.UIFigure.HandleVisibility = fhv;
                if app.datapicker.Value
                    break
                end


            end
        end

        % Value changed function: SelectedTextArea, SessionsListBox
        function ListBoxValueChanged(app, event)

            % my code

            app.A1CheckBoxpre.Enable='off';

            app.S1CheckBoxpre.Enable='off';

            app.S2CheckBoxpre.Enable='off';

            app.wM2CheckBoxpre.Enable='off';

            app.ALMCheckBoxpre.Enable='off';


            value = app.SessionsListBox.Value;
            app.variables.SelectedSession=value
            app.SelectedTextArea.Value=value


            % update sesssion
            sessionname=app.variables.SelectedSession  % selected session
            app.variables.Indecies_SelectedSession=find(strcmp(cell2mat(sessionname),[app.variables.PSTHMat.session_id])) ;%index of probes that have same session
            app.variables.Areas_SelectedSession=[app.variables.PSTHMat(app.variables.Indecies_SelectedSession).probe_location]   ;                                         % area in that session that recorded simultaneously


            %which area are in current session
            for Iarea=1:length(app.variables.Areas_SelectedSession)
                currArea=cell2mat(app.variables.Areas_SelectedSession(Iarea));
                switch currArea
                    case 'A1'
                        app.A1CheckBoxpre.Enable='on';

                    case 'wS1'
                        app.S1CheckBoxpre.Enable='on';

                    case 'wS2'
                        app.S2CheckBoxpre.Enable='on';

                    case 'wM2'
                        app.wM2CheckBoxpre.Enable='on';

                    case 'ALM'
                        app.ALMCheckBoxpre.Enable='on';


                end


            end

             if isempty([app.variables.PSTHMat( app.variables.Indecies_SelectedSession(1)).Notes])
                
                 app.TextArea.Value=[{'NO'};{'Note'}]
             else
               app.TextArea.Value=[app.variables.PSTHMat(app.variables.Indecies_SelectedSession(1)).Notes{:}]

             end


            Signal_allArea_allCondition = UpdataPsthPlot(app)
            PlotMovementsSignal(app)


            PlotBehaviourPerformance(app)


            PlotProbeLocation(app)


            % end of my code


        end

        % Value changed function: A1CheckBoxpre
        function IsA1pre(app, event)
            app.variables.IsA1pre = app.A1CheckBoxpre.Value;

        end

        % Callback function
        function IsA1post(app, event)
            app.variables.IsA1post = app.A1CheckBoxpost.Value;

        end

        % Value changed function: S1CheckBoxpre
        function IsS1pre(app, event)
            app.variables.wS1pre = app.wS1CheckBoxpre.Value;

        end

        % Callback function
        function IsS1post(app, event)
            app.variables.wS1post = app.wS1CheckBoxpost.Value;

        end

        % Callback function
        function isFSpre(app, event)
            app.variables.isFSpre = app.FSCheckBoxA1pre.Value;

        end

        % Callback function
        function isFSpost(app, event)
            app.variables.isFSpost = app.FSCheckBoxpost.Value;

        end

        % Callback function
        function isRSpre(app, event)
            app.variables.isRSpre = app.RSCheckBoxA1pre.Value;

        end

        % Callback function
        function isRSpost(app, event)
            app.variables.isRSpost = app.RSCheckBoxpost.Value;

        end

        % Callback function
        function isS1FSpost(app, event)
            app.variables.iswS1FSpost = app.FSCheckBoxwS1post.Value;

        end

        % Callback function
        function isS1RSpost(app, event)
            app.variables.iswS1RSpost = app.RSCheckBoxwS1post.Value;

        end

        % Callback function
        function isS1FSpre(app, event)
            app.variables.iswS1FSpre = app.FSCheckBoxwS1pre.Value;

        end

        % Callback function
        function isS1RSpre(app, event)
            app.variables.iswS1RSpre = app.RSCheckBoxwS1pre.Value;

        end

        % Callback function
        function PLOT(app, event)

        end

        % Callback function
        function datapickerValueChanged(app, event)
            app.variables.stratstopdatapicker = app.datapicker.Value;

        end

        % Callback function
        function is(app, event)
            value = app.RSCheckBoxA1post.Value;

        end

        % Callback function
        function isA1RSpost(app, event)
            app.variables.isA1RSpost = app.RSCheckBoxA1post.Value;

        end

        % Callback function
        function isA1FSpost(app, event)
            app.variables.isA1FSpost  = app.FSCheckBoxA1post.Value;

        end

        % Callback function
        function isA1FSpre(app, event)
            app.variables.isA1FSpre = app.FSCheckBoxA1pre.Value;

        end

        % Callback function
        function isA1RSpre(app, event)
            app.variables.isA1RSpre = app.RSCheckBoxA1pre.Value;

        end

        % Callback function
        function isS2FSpre(app, event)
            app.variables.iswS2FSpre = app.FSCheckBoxwS2pre.Value;

        end

        % Callback function
        function isS2RSpre(app, event)
            app.variables.iswS2RSpre = app.RSCheckBoxwS2pre.Value;

        end

        % Callback function
        function isS2FSpost(app, event)
            app.variables.iswS2FSpost = app.FSCheckBoxwS2post.Value;

        end

        % Callback function
        function isS2RSpost(app, event)
            app.variables.iswS2RSpost = app.RSCheckBoxwS2post.Value;

        end

        % Value changed function: S2CheckBoxpre
        function IsS2pre(app, event)
            app.variables. IswS2pre= app.wS2CheckBoxpre.Value;

        end

        % Callback function
        function IsS2post(app, event)
            app.variables. IswS2post = app.wS2CheckBoxpost.Value;

        end

        % Value changed function: wM2CheckBoxpre
        function iswM2pre(app, event)
            app.variables. iswM2pre = app.wM2CheckBoxpre.Value;

        end

        % Callback function
        function iswM2RSpre(app, event)
            app.variables. iswM2RSpre = app.RSCheckBoxwM2pre.Value;

        end

        % Callback function
        function iswM2FSpre(app, event)
            app.variables. iswM2FSpre = app.FSCheckBoxwM2pre.Value;

        end

        % Callback function
        function iswM2RSpost(app, event)
            app.variables. iswM2RSpost = app.RSCheckBoxwM2post.Value;

        end

        % Callback function
        function iswM2FSpost(app, event)
            app.variables. iswM2FSpost = app.FSCheckBoxwM2post.Value;

        end

        % Callback function
        function iswM2post(app, event)
            app.variables.iswM2post = app.wM2CheckBoxpost.Value;

        end

        % Value changed function: ALMCheckBoxpre
        function isALMpre(app, event)
            app.variables.isALMpre = app.ALMCheckBoxpre.Value;

        end

        % Callback function
        function isALMpost(app, event)
            app.variables.isALMpost = app.ALMCheckBoxpost.Value;

        end

        % Callback function
        function isALMFSpre(app, event)
            app.variables.isALMFSpre = app.FSCheckBoxALMpre.Value;

        end

        % Callback function
        function isALMRSpre(app, event)
            app.variables.isALMRSpre = app.RSCheckBoxALMpre.Value;

        end

        % Callback function
        function isALMRSpost(app, event)
            app.variables.isALMRSpost = app.RSCheckBoxALMpost.Value;

        end

        % Callback function
        function isALMFSpost(app, event)
            app.variables.isALMFSpost = app.FSCheckBoxALMpost.Value;

        end

        % Value changed function: TrialTypeListBox
        function TrialTypeListBoxValueChanged(app, event)
            Items=app.TrialTypeListBox.Items
            value = app.TrialTypeListBox.Value;
            TrialType=find(strcmp(app.TrialTypeListBox.Items,value));
            app.variables.TrialType=TrialType;


            Signal_allArea_allCondition = UpdataPsthPlot(app)

            PlotMovementsSignal(app)


            PlotBehaviourPerformance(app)


            PlotProbeLocation(app)


        end

        % Value changed function: LickStateListBox
        function LickStateListBoxValueChanged(app, event)
            Items=app.TrialTypeListBox.Items;
            value = app.LickStateListBox.Value;
            LickState=find(strcmp(app.LickStateListBox.Items,value));

            switch LickState
                case 1
                 LickState=1
                case 2
                 LickState=0   
            end

            app.variables.LickState=LickState;


            Signal_allArea_allCondition = UpdataPsthPlot(app)

            PlotMovementsSignal(app)


            PlotBehaviourPerformance(app)


            PlotProbeLocation(app)

        end

        % Value changed function: QuiesenceListBox
        function QuiesenceListBoxValueChanged(app, event)
            value = app.QuiesenceListBox.Value;
            app.variables.QuietState=value;


            Signal_allArea_allCondition = UpdataPsthPlot(app)
            PlotMovementsSignal(app)


            PlotBehaviourPerformance(app)


            PlotProbeLocation(app)

        end

        % Value changed function: CellTypeListBox
        function CellTypeListBoxValueChanged(app, event)
            value = app.CellTypeListBox.Value;
            app.variables.CellType=value;


            Signal_allArea_allCondition = UpdataPsthPlot(app)
            
            PlotMovementsSignal(app)


            PlotBehaviourPerformance(app)


            PlotProbeLocation(app)

        end

        % Value changed function: BaselineSubtractionCheckBox
        function BaselineSubtractionCheckBoxValueChanged(app, event)
            value = app.BaselineSubtractionCheckBox.Value;
            app.variables.BaselineSubtraction=value
            Signal_allArea_allCondition = UpdataPsthPlot(app)
            PlotMovementsSignal(app)


            PlotBehaviourPerformance(app)


            PlotProbeLocation(app)


        end

        % Value changed function: PlotSEMCheckBox
        function PlotSEMCheckBoxValueChanged(app, event)
            value = app.PlotSEMCheckBox.Value;
            app.variables.PlotSEM=value


            Signal_allArea_allCondition = UpdataPsthPlot(app)
            PlotMovementsSignal(app)


            PlotBehaviourPerformance(app)


            PlotProbeLocation(app)
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            for iSelected_Ind=app.variables.Indecies_SelectedSession  

            [app.variables.PSTHMat(iSelected_Ind).Notes]={value};
            end

        end

        % Button pushed function: SaveNotesButton
        function SaveNotesButtonPushed(app, event)
            assignin('base','psth_mat', app.variables.PSTHMat)
        end

        % Value changed function: FixYaxisCheckBox
        function FixYaxisCheckBoxValueChanged(app, event)
            value = app.FixYaxisCheckBox.Value;
            if value
            app.STA.YLim=[str2num(app.YaxisLimit.Value)]
            % yticks(app.STA,[-30 -20 -10 0 10 20 30 40 50 60 70 100])
             yticklabels(app.STA,'auto')
             grid(app.STA,'on')
            else
            app.STA.YLimMode="auto"
            grid(app.STA,'off')

            end
        end

        % Value changed function: completionStateBox
        function completionStateBoxValueChanged(app, event)
            value = app.completionStateBox.Value;
            app.variables.completion_state=value;


            Signal_allArea_allCondition = UpdataPsthPlot(app)
            PlotMovementsSignal(app)


            PlotBehaviourPerformance(app)


            PlotProbeLocation(app)
            
        end

        % Value changed function: YaxisLimit
        function YaxisLimitValueChanged(app, event)
            value = app.YaxisLimit.Value;
            
        end

        % Value changed function: FixXaxisCheckBox
        function FixXaxisCheckBoxValueChanged(app, event)
            value = app.FixXaxisCheckBox.Value;
            if value
            app.STA.XLim=[str2num(app.XaxisLimit.Value)]
            % yticks(app.STA,[-30 -20 -10 0 10 20 30 40 50 60 70 100])
             xticklabels(app.STA,'auto')
             grid(app.STA,'on')
            else
            app.STA.XLimMode="auto"
            grid(app.STA,'off')

            end
        end

        % Value changed function: XaxisLimit
        function XaxisLimitValueChanged(app, event)
            value = app.XaxisLimit.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.distribution = uiaxes(app.GridLayout);
            title(app.distribution, 'Probe Location')
            xlabel(app.distribution, 'X')
            ylabel(app.distribution, 'Y')
            app.connectivitymat = uiaxes(app.GridLayout);
            title(app.connectivitymat, 'Behavioral performance ')
            xlabel(app.connectivitymat, 'Trial Number')
            ylabel(app.connectivitymat, 'Lick Probability (%)')
            app.STA = uiaxes(app.GridLayout);
            title(app.STA, 'Neuronal Activity / Movements ')
            xlabel(app.STA, 'Time (s)')
            ylabel(app.STA, 'firing_rate (Hz)')
            app.movement_ax = uiaxes(app.GridLayout);
            title(app.movement_ax, 'Movement signals')
            xlabel(app.movement_ax, 'Time(s)')
            ylabel(app.movement_ax, 'a.u')
            zlabel(app.movement_ax, 'Z')
            app.SessionsListBoxLabel = uilabel(app.GridLayout);
            app.SessionsListBoxLabel.Text = 'Sessions';
            app.SessionsListBox = uilistbox(app.GridLayout);
            app.SessionsListBox.Items = {'Item 1', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10', 'Item 2', 'Item 3', 'Item 4'};
            app.SessionsListBox.Multiselect = 'on';
            app.SessionsListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.SessionsListBox.Value = {'Item 1'};
            app.SelectedTextAreaLabel = uilabel(app.GridLayout);
            app.SelectedTextAreaLabel.Text = 'Selected ';
            app.SelectedTextArea = uitextarea(app.GridLayout);
            app.SelectedTextArea.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.SelectedTextArea.Value = {'---'};
            app.ALMCheckBoxpre = uicheckbox(app.GridLayout);
            app.ALMCheckBoxpre.ValueChangedFcn = createCallbackFcn(app, @isALMpre, true);
            app.ALMCheckBoxpre.Text = 'ALM';
            app.wM2CheckBoxpre = uicheckbox(app.GridLayout);
            app.wM2CheckBoxpre.ValueChangedFcn = createCallbackFcn(app, @iswM2pre, true);
            app.wM2CheckBoxpre.Text = 'wM2';
            app.S2CheckBoxpre = uicheckbox(app.GridLayout);
            app.S2CheckBoxpre.ValueChangedFcn = createCallbackFcn(app, @IsS2pre, true);
            app.S2CheckBoxpre.Text = 'S2';
            app.S1CheckBoxpre = uicheckbox(app.GridLayout);
            app.S1CheckBoxpre.ValueChangedFcn = createCallbackFcn(app, @IsS1pre, true);
            app.S1CheckBoxpre.Text = 'S1';
            app.A1CheckBoxpre = uicheckbox(app.GridLayout);
            app.A1CheckBoxpre.ValueChangedFcn = createCallbackFcn(app, @IsA1pre, true);
            app.A1CheckBoxpre.Text = 'A1';
            app.TrialTypeListBoxLabel = uilabel(app.GridLayout);
            app.TrialTypeListBoxLabel.Text = 'TrialType';
            app.TrialTypeListBox = uilistbox(app.GridLayout);
            app.TrialTypeListBox.Items = {'Go     Tone - Whisker ', 'Go     Tone -  ', 'Nogo Tone - Whisker ', 'Nogo Tone -  ', 'No     Tone - Whisker '};
            app.TrialTypeListBox.ValueChangedFcn = createCallbackFcn(app, @TrialTypeListBoxValueChanged, true);
            app.TrialTypeListBox.Value = 'Go     Tone - Whisker ';
            app.LickStateListBoxLabel = uilabel(app.GridLayout);
            app.LickStateListBoxLabel.Text = 'LickState';
            app.LickStateListBox = uilistbox(app.GridLayout);
            app.LickStateListBox.Items = {'Lick', 'NoLick'};
            app.LickStateListBox.ValueChangedFcn = createCallbackFcn(app, @LickStateListBoxValueChanged, true);
            app.LickStateListBox.Value = 'Lick';
            app.QuiesenceListBoxLabel = uilabel(app.GridLayout);
            app.QuiesenceListBoxLabel.Text = 'Quiesence';
            app.QuiesenceListBox = uilistbox(app.GridLayout);
            app.QuiesenceListBox.Items = {'Quiet_(whisker_speed)', 'Quiet_(jaw_movement)', 'Quiet_(jaw & whisker)', 'Non_quiet', 'All_trials'};
            app.QuiesenceListBox.ValueChangedFcn = createCallbackFcn(app, @QuiesenceListBoxValueChanged, true);
            app.QuiesenceListBox.Value = 'All_trials';
            app.CellTypeListBoxLabel = uilabel(app.GridLayout);
            app.CellTypeListBoxLabel.Text = 'CellType';
            app.CellTypeListBox = uilistbox(app.GridLayout);
            app.CellTypeListBox.Items = {'RS', 'FS', 'All'};
            app.CellTypeListBox.ValueChangedFcn = createCallbackFcn(app, @CellTypeListBoxValueChanged, true);
            app.CellTypeListBox.Value = 'RS';
            app.BaselineSubtractionCheckBox = uicheckbox(app.GridLayout);
            app.BaselineSubtractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @BaselineSubtractionCheckBoxValueChanged, true);
            app.BaselineSubtractionCheckBox.Text = 'Baseline Subtraction';
            app.BaselineSubtractionCheckBox.Value = true;
            app.PlotSEMCheckBox = uicheckbox(app.GridLayout);
            app.PlotSEMCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotSEMCheckBoxValueChanged, true);
            app.PlotSEMCheckBox.Text = 'Plot+/- SEM ';
            app.TextAreaLabel = uilabel(app.GridLayout);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.SaveNotesButton = uibutton(app.GridLayout, 'push');
            app.SaveNotesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveNotesButtonPushed, true);
            app.SaveNotesButton.Text = 'Save Notes';
            app.FixYaxisCheckBox = uicheckbox(app.GridLayout);
            app.FixYaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixYaxisCheckBoxValueChanged, true);
            app.FixYaxisCheckBox.Text = 'Fix Y axis';
            app.early_lickcomplete_Label = uilabel(app.GridLayout);
            app.early_lickcomplete_Label.Text = 'early_lick/complete _';
            app.completionStateBox = uilistbox(app.GridLayout);
            app.completionStateBox.Items = {'completed_trials', 'early_licks'};
            app.completionStateBox.ValueChangedFcn = createCallbackFcn(app, @completionStateBoxValueChanged, true);
            app.completionStateBox.Value = 'completed_trials';
            app.limitminmaxLabel = uilabel(app.GridLayout);
            app.limitminmaxLabel.Text = 'min,max';
            app.YaxisLimit = uieditfield(app.GridLayout, 'text');
            app.YaxisLimit.ValueChangedFcn = createCallbackFcn(app, @YaxisLimitValueChanged, true);
            app.YaxisLimit.Value = '-1,20';
            app.FixXaxisCheckBox = uicheckbox(app.GridLayout);
            app.FixXaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @FixXaxisCheckBoxValueChanged, true);
            app.FixXaxisCheckBox.Text = 'Fix X axis';
            app.TextArea2Label = uilabel(app.GridLayout);
            app.TextArea2Label.Text = 'min,max';
            app.XaxisLimit = uitextarea(app.GridLayout);
            app.XaxisLimit.ValueChangedFcn = createCallbackFcn(app, @XaxisLimitValueChanged, true);
            app.XaxisLimit.Value = {'0,1'};
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'miciii.png');
            app.ContextdependentwhikerdetectiontaskDataViewerLabel = uilabel(app.GridLayout);
            app.ContextdependentwhikerdetectiontaskDataViewerLabel.Text = 'Context dependent whiker detection task - Data Viewer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end